classdef labTopicosAppV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CambiarcontrolButton           matlab.ui.control.Button
        ReferenciaDropDown             matlab.ui.control.DropDown
        ReferenciaDropDownLabel        matlab.ui.control.Label
        EnviarButton                   matlab.ui.control.Button
        Elvalormximodetorqueesde084NmLabel  matlab.ui.control.Label
        PeriodoEditField               matlab.ui.control.NumericEditField
        PeriodosEditFieldLabel         matlab.ui.control.Label
        AmplitudEditField              matlab.ui.control.NumericEditField
        AmplitudNmLabel                matlab.ui.control.Label
        ControlDropDown                matlab.ui.control.DropDown
        ControlDropDownLabel           matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        ControlSignalTab               matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        UIAxesControl                  matlab.ui.control.UIAxes
        PosicinTab                     matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        UIAxes4                        matlab.ui.control.UIAxes
        VelocidadTab                   matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        UIAxesVelocidad                matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        RefOutTab                      matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        UIAxesRefOut                   matlab.ui.control.UIAxes
        ErrorTab                       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxesError                    matlab.ui.control.UIAxes
        LaboratoriodecontrolIILabel    matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TiempoplotEditField            matlab.ui.control.NumericEditField
        TiempoplotEditFieldLabel       matlab.ui.control.Label
        Energau2EditField              matlab.ui.control.NumericEditField
        EnergaLabel                    matlab.ui.control.Label
        Errore2EditField               matlab.ui.control.NumericEditField
        ErroreLabel                    matlab.ui.control.Label
        FigurasdemritoporperiodoLabel  matlab.ui.control.Label
        LabelCopyright                 matlab.ui.control.Label
        ControldiscretodetorquedeunmotorDCLabel  matlab.ui.control.Label
        ConectarButton                 matlab.ui.control.Button
        BaudRateDropDown               matlab.ui.control.DropDown
        BaudRateDropDownLabel          matlab.ui.control.Label
        PuertoDropDown                 matlab.ui.control.DropDown
        PuertoDropDownLabel            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        
        
        %control=[{q0};{q1};{q2};{s0}];
        continuo=[0;0.0597;-0.0467;-0.9893];
        discreto=[15.7769;-15.6914;0;-0.9958];
        lgr=[0;5.4315;-5.069;-0.9969];
        anulacionPlanta=[0;6.842;-6.277;-0.9886];
        deadBeat=[0;1.73875;-1.59513;-0.9886];
        compensador=[0;2.0083;-1.94704;-0.9902];
        servo=[0;2.0083;-1.94704;-0.9902];
        
        controles;
        %
        s;          %serial
        
        % circshift(v,length(v)-1)
        % graficas
        sampleTime=0.005;
        totalSamples;
        dataTime;
        dataReference;
        dataOut;
        dataError;
        dataControl;
        dataPosicion;
        dataVelocidad;
        dataPulses;
        
        counter=0;
    end
    
    methods (Access = private)
        
        function [] = setLenData(app)
            totalTime = app.TiempoplotEditField.Value;
            app.dataTime = 0:app.sampleTime*10:totalTime;
            app.totalSamples=length(app.dataTime);
            app.dataReference=zeros(1,app.totalSamples);
            app.dataOut=zeros(1,app.totalSamples);
            app.dataError=zeros(1,app.totalSamples);
            app.dataControl=zeros(1,app.totalSamples);
            app.dataPosicion=zeros(1,app.totalSamples);
            app.dataVelocidad=zeros(1,app.totalSamples);
            app.dataPulses=zeros(1,app.totalSamples);
            disp("total datos a plotear: "+ app.totalSamples)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.controles={app.continuo,app.discreto,app.lgr, ...
                app.anulacionPlanta, app.deadBeat,app.compensador,app.servo};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x', 231};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: EnviarButton
        function EnviarButtonPushed(app, event)
            tipoReferencia=app.ReferenciaDropDown.Value;
            reference=app.AmplitudEditField.Value;
            periodo=app.PeriodoEditField.Value;
            msg="R"+tipoReferencia+","+reference+","+periodo;
            disp(msg)
            writeline(app.s,msg);
        end

        % Button pushed function: CambiarcontrolButton
        function CambiarcontrolButtonPushed(app, event)
            indexControl=app.ControlDropDown.Value;
            coefs=app.controles{indexControl};
            msg="C"+coefs(1)+","+coefs(2)+","+coefs(3)+","+coefs(4);
            disp(msg)
            writeline(app.s,msg);

        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            
            if app.ConectarButton.Text=="Conectar"
                
                com=app.PuertoDropDown.Value;
                baudRate=str2double(app.BaudRateDropDown.Value);
                app.s = serialport(com,baudRate);
                app.ConectarButton.Text="Desconectar";
                configureCallback(app.s,"terminator",@readSerialData)
                setLenData(app);
            
            elseif app.ConectarButton.Text=="Desconectar"
                app.s.delete;
                app.ConectarButton.Text="Conectar";
            end
            
            function readSerialData(src,~)
                %{actualRef},{out},{control},{pulses}
                data = readline(src);
                data = str2double(split(data,","));
                len = app.totalSamples;
                app.dataReference = circshift(app.dataReference,len-1);
                app.dataReference(end) = data(1);
                app.dataOut = circshift(app.dataOut,len-1);
                app.dataOut(end) = data(2);
                app.dataControl = circshift(app.dataControl,len-1);
                app.dataControl(end) = data(3);
                %velocidad
                app.dataVelocidad = circshift(app.dataVelocidad,len-1);
                app.dataVelocidad(end) = 60*data(4)/(22*5e-3*78);
                app.dataError=app.dataReference-app.dataOut;
                app.counter=app.counter+1;
                if(app.counter==5)
                    plot(app.UIAxesRefOut,app.dataTime,app.dataOut,app.dataTime,app.dataReference);
                    %plot(app.UIAxesRefOut,app.dataTime,app.dataReference);
                    
                    plot(app.UIAxesError,app.dataTime,app.dataError);
                    app.counter=0;
                    
                    plot(app.UIAxesControl,app.dataTime,app.dataControl);
                    plot(app.UIAxesVelocidad,app.dataTime,app.dataVelocidad);
                end
                
            end
            
        end

        % Drop down opening function: PuertoDropDown
        function PuertoDropDownOpening(app, event)
            puertos = split(serialportlist," ");
            app.PuertoDropDown.Items = puertos;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: TiempoplotEditField
        function TiempoplotEditFieldValueChanged(app, event)
            setLenData(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ControlDropDownLabel = uilabel(app.LeftPanel);
            app.ControlDropDownLabel.Text = 'Control';
            app.ControlDropDown = uidropdown(app.LeftPanel);
            app.ControlDropDown.Items = {'Continuo - D', 'Discreto', 'LGR', 'Anulación planta', 'Dead Beat', 'Frecuencia', 'Servosistema'};
            app.ControlDropDown.ItemsData = [1 2 3 4 5 6 7];
            app.ControlDropDown.Value = 1;
            app.AmplitudNmLabel = uilabel(app.LeftPanel);
            app.AmplitudNmLabel.Text = 'Valor max* [N*m]';
            app.AmplitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditField.Value = 0.7;
            app.PeriodosEditFieldLabel = uilabel(app.LeftPanel);
            app.PeriodosEditFieldLabel.Text = 'Periodo [s]';
            app.PeriodoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PeriodoEditField.Value = 10;
            app.Elvalormximodetorqueesde084NmLabel = uilabel(app.LeftPanel);
            app.Elvalormximodetorqueesde084NmLabel.Text = '* El valor máximo de torque es de 0.84 N*m ';
            app.EnviarButton = uibutton(app.LeftPanel, 'push');
            app.EnviarButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.EnviarButton.Icon = 'logo_mecatronica_basicoPeque.png';
            app.EnviarButton.Text = 'Enviar';
            app.ReferenciaDropDownLabel = uilabel(app.LeftPanel);
            app.ReferenciaDropDownLabel.Text = 'Referencia';
            app.ReferenciaDropDown = uidropdown(app.LeftPanel);
            app.ReferenciaDropDown.Items = {'Cuadrada', 'Triangular', 'Senoidal'};
            app.ReferenciaDropDown.ItemsData = [1 2 3];
            app.ReferenciaDropDown.Value = 2;
            app.CambiarcontrolButton = uibutton(app.LeftPanel, 'push');
            app.CambiarcontrolButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarcontrolButtonPushed, true);
            app.CambiarcontrolButton.Text = 'Cambiar control';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.LaboratoriodecontrolIILabel = uilabel(app.GridLayout2);
            app.LaboratoriodecontrolIILabel.Text = 'Laboratorio de control II';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.RefOutTab = uitab(app.TabGroup);
            app.RefOutTab.Title = 'Ref & Out';
            app.GridLayout3 = uigridlayout(app.RefOutTab);
            app.UIAxesRefOut = uiaxes(app.GridLayout3);
            xlabel(app.UIAxesRefOut, 'time [s]')
            ylabel(app.UIAxesRefOut, '[N*m]')
            zlabel(app.UIAxesRefOut, 'Z')
            app.ErrorTab = uitab(app.TabGroup);
            app.ErrorTab.Title = 'Error';
            app.GridLayout4 = uigridlayout(app.ErrorTab);
            app.UIAxesError = uiaxes(app.GridLayout4);
            xlabel(app.UIAxesError, 'time [s]')
            ylabel(app.UIAxesError, '[N*m]')
            zlabel(app.UIAxesError, 'Z')
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.ControlSignalTab = uitab(app.TabGroup2);
            app.ControlSignalTab.Title = 'Control Signal';
            app.GridLayout5 = uigridlayout(app.ControlSignalTab);
            app.UIAxesControl = uiaxes(app.GridLayout5);
            xlabel(app.UIAxesControl, 'time [s]')
            ylabel(app.UIAxesControl, '[V]')
            zlabel(app.UIAxesControl, 'Z')
            app.PosicinTab = uitab(app.TabGroup2);
            app.PosicinTab.Title = 'Posición';
            app.GridLayout6 = uigridlayout(app.PosicinTab);
            app.UIAxes4 = uiaxes(app.GridLayout6);
            xlabel(app.UIAxes4, 'time [s]')
            ylabel(app.UIAxes4, '[degrees]')
            zlabel(app.UIAxes4, 'Z')
            app.VelocidadTab = uitab(app.TabGroup2);
            app.VelocidadTab.Title = 'Velocidad';
            app.GridLayout7 = uigridlayout(app.VelocidadTab);
            app.UIAxesVelocidad = uiaxes(app.GridLayout7);
            xlabel(app.UIAxesVelocidad, 'time [s]')
            ylabel(app.UIAxesVelocidad, '[RPM]')
            zlabel(app.UIAxesVelocidad, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.PuertoDropDownLabel = uilabel(app.RightPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.RightPanel);
            app.PuertoDropDown.Items = {'COM 9', 'COM 8'};
            app.PuertoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PuertoDropDownOpening, true);
            app.PuertoDropDown.Value = 'COM 9';
            app.BaudRateDropDownLabel = uilabel(app.RightPanel);
            app.BaudRateDropDownLabel.Text = 'BaudRate';
            app.BaudRateDropDown = uidropdown(app.RightPanel);
            app.BaudRateDropDown.Items = {'9600', '115200'};
            app.BaudRateDropDown.Value = '115200';
            app.ConectarButton = uibutton(app.RightPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.ControldiscretodetorquedeunmotorDCLabel = uilabel(app.RightPanel);
            app.ControldiscretodetorquedeunmotorDCLabel.Text = {'Control discreto de'; 'torque de un motor DC'};
            app.LabelCopyright = uilabel(app.RightPanel);
            app.LabelCopyright.Text = {'El uso no autorizado así como la reproducción'; 'total o parcial de este contenido por cualquier '; 'persona o entidad, estará en contra de los '; 'derechos de autor. ©'};
            app.FigurasdemritoporperiodoLabel = uilabel(app.RightPanel);
            app.FigurasdemritoporperiodoLabel.Text = 'Figuras de mérito (por periodo)';
            app.ErroreLabel = uilabel(app.RightPanel);
            app.ErroreLabel.Text = 'Error [ Σe^2]';
            app.Errore2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Errore2EditField.Editable = 'off';
            app.EnergaLabel = uilabel(app.RightPanel);
            app.EnergaLabel.Text = 'Energía [ Σu^2] ';
            app.Energau2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Energau2EditField.Editable = 'off';
            app.TiempoplotEditFieldLabel = uilabel(app.RightPanel);
            app.TiempoplotEditFieldLabel.Text = 'Tiempo plot';
            app.TiempoplotEditField = uieditfield(app.RightPanel, 'numeric');
            app.TiempoplotEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoplotEditFieldValueChanged, true);
            app.TiempoplotEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labTopicosAppV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end